classdef exp3UI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TextArea                      matlab.ui.control.TextArea
        StartJudgingButton            matlab.ui.control.Button
        ResultLabel                   matlab.ui.control.Label
        PrimeNumberJudgerLabel        matlab.ui.control.Label
        InputforJdgingEditField       matlab.ui.control.NumericEditField
        InputforJdgingEditFieldLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartJudgingButton
        function StartJudgingButtonPushed(app, event)
            x = app.InputforJdgingEditField.Value;
            [Judgenum,i] = PrimeJudge(x);
            if(Judgenum==0)
                cellArrayText{1} = sprintf('%d is not one prime.\n',x);
                cellArrayText{2} = sprintf('Factorization:%d=%d*%d\n',x,i,x/i);
                app.TextArea.Value = cellArrayText;
            end
            if(Judgenum==1)
                cellArrayText{1} = sprintf('%d is one prime.\n',x);
                app.TextArea.Value = cellArrayText;
            end
        end

        % Value changing function: TextArea
        function TextAreaValueChanging(app, event)
            changingValue = event.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.InputforJdgingEditFieldLabel = uilabel(app.UIFigure);
            app.InputforJdgingEditFieldLabel.Text = 'Input for Jdging';
            app.InputforJdgingEditField = uieditfield(app.UIFigure, 'numeric');
            app.PrimeNumberJudgerLabel = uilabel(app.UIFigure);
            app.PrimeNumberJudgerLabel.Text = 'Prime Number Judger';
            app.ResultLabel = uilabel(app.UIFigure);
            app.ResultLabel.Text = 'Result';
            app.StartJudgingButton = uibutton(app.UIFigure, 'push');
            app.StartJudgingButton.ButtonPushedFcn = createCallbackFcn(app, @StartJudgingButtonPushed, true);
            app.StartJudgingButton.Text = 'Start Judging';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.ValueChangingFcn = createCallbackFcn(app, @TextAreaValueChanging, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = exp3UI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end